classdef simon_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        Label         matlab.ui.control.Label
        ScoreLabel    matlab.ui.control.Label
        yellow_flash  matlab.ui.control.Image
        red_flash     matlab.ui.control.Image
        green_flash   matlab.ui.control.Image
        blue_flash    matlab.ui.control.Image
        GreenButton   matlab.ui.control.Button
        YellowButton  matlab.ui.control.Button
        BlueButton    matlab.ui.control.Button
        RedButton     matlab.ui.control.Button
        QuitButton    matlab.ui.control.Button
        Image         matlab.ui.control.Image
    end

    properties (Access = private)
        game_sequence= randi(4, [1,85]); % creates the sequence of buttons to match throughout the entire game. A new, random sequence for each game. 
        sequence_length= 1; %creates a variable that sets the length of the simon sequence. This will increase by 1 each time the user inputs a correct sequence.
        user_sequence=[]; %initiates a blank array to be populated by the user
    end
    
    methods (Access = private) %% DONT EDIT: this is the break between properties and functions. Properties define variables. Functions execute. 
    %%%Game funcitons

    function button_flash_and_sound(app)
        for i=1:app.sequence_length %only iterates the game loop X # of times, where X= sequence_length.
            current_button=app.game_sequence(i);

            if current_button == 1 %red button sequence
                pause(1.5);
              app.red_flash.Visible="on";
              [y,Fs]=audioread("https://s3.amazonaws.com/freecodecamp/simonSound2.mp3");
              sound(y,Fs);
              pause(1.0);
              app.red_flash.Visible="off";
            elseif current_button == 2 %green button sequence
                pause(1.5);
               app.green_flash.Visible = "on";
               [y,Fs]=audioread("https://s3.amazonaws.com/freecodecamp/simonSound1.mp3");
               sound(y,Fs);
               pause(1.0);
               app.green_flash.Visible="off";
            elseif current_button == 3 %yellow button sequence
                pause(1.5);
                app.yellow_flash.Visible = "on";
                [y,Fs]=audioread("https://s3.amazonaws.com/freecodecamp/simonSound4.mp3");
                sound(y,Fs);
                pause(1.0);
                app.yellow_flash.Visible="off";
            else %blue button sequence
                pause(1.5);
                app.blue_flash.Visible="on";
                [y,Fs]=audioread("https://s3.amazonaws.com/freecodecamp/simonSound3.mp3");
                sound(y,Fs);
                pause(1.0);
                app.blue_flash.Visible="off";
            end
         end
    end
    
  %%%%%%%%%
    end %methods end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            button_flash_and_sound(app);
            
    sequence = [];
    user_sequence=[];
    pause(10)
    
        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
        delete(app) %quits app if quit button is pushed
        end

        % Button pushed function: GreenButton
        function GreenButtonPushed(app, event)
            user_sequence=[user_sequence 1]
        end

        % Button pushed function: YellowButton
        function YellowButtonPushed(app, event)
            user_sequence=[user_sequence 3]
        end

        % Button pushed function: RedButton
        function RedButtonPushed(app, event)
            user_sequence=[user_sequence 2]
        end

        % Button pushed function: BlueButton
        function BlueButtonPushed(app, event)
            user_sequence=[user_sequence 4]
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'images', 'simon_1.0.jpg');
            app.QuitButton = uibutton(app.UIFigure, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            app.RedButton = uibutton(app.UIFigure, 'push');
            app.RedButton.ButtonPushedFcn = createCallbackFcn(app, @RedButtonPushed, true);
            app.RedButton.Visible = 'off';
            app.RedButton.Text = 'Red';
            app.BlueButton = uibutton(app.UIFigure, 'push');
            app.BlueButton.ButtonPushedFcn = createCallbackFcn(app, @BlueButtonPushed, true);
            app.BlueButton.Visible = 'off';
            app.BlueButton.Text = '';
            app.YellowButton = uibutton(app.UIFigure, 'push');
            app.YellowButton.ButtonPushedFcn = createCallbackFcn(app, @YellowButtonPushed, true);
            app.YellowButton.Visible = 'off';
            app.YellowButton.Text = 'Yellow';
            app.GreenButton = uibutton(app.UIFigure, 'push');
            app.GreenButton.ButtonPushedFcn = createCallbackFcn(app, @GreenButtonPushed, true);
            app.GreenButton.Visible = 'off';
            app.GreenButton.Text = 'Green';
            app.blue_flash = uiimage(app.UIFigure);
            app.blue_flash.Visible = 'off';
            app.blue_flash.ImageSource = fullfile(pathToMLAPP, 'images', 'blue.jpg');
            app.green_flash = uiimage(app.UIFigure);
            app.green_flash.Visible = 'off';
            app.green_flash.ImageSource = fullfile(pathToMLAPP, 'images', 'green.jpg');
            app.red_flash = uiimage(app.UIFigure);
            app.red_flash.Visible = 'off';
            app.red_flash.ImageSource = fullfile(pathToMLAPP, 'images', 'red.jpg');
            app.yellow_flash = uiimage(app.UIFigure);
            app.yellow_flash.Visible = 'off';
            app.yellow_flash.ImageSource = fullfile(pathToMLAPP, 'images', 'simon_1.0.jpg');
            app.ScoreLabel = uilabel(app.UIFigure);
            app.ScoreLabel.Text = 'Score:';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = simon_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end